classdef imageCompression < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LossyCompressionPanel  matlab.ui.container.Panel
        images                 matlab.ui.container.Panel
        uncompress             matlab.ui.control.Image
        compress               matlab.ui.control.Image
        control                matlab.ui.container.Panel
        BrowseButton           matlab.ui.control.Button
        CompressButton_2       matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        data                   matlab.ui.container.Panel
        OrignalSizeLabel       matlab.ui.control.Label
        CompressionRatioLabel  matlab.ui.control.Label
        CompressionSizeLabel   matlab.ui.control.Label
        EditField              matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        EditField_3            matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct;*.tif'}, 'Select File to Open');
           fullname = [filepath, filename];
           
           app.uncompress.ImageSource = fullname;
        end

        % Button pushed function: CompressButton_2
        function CompressButton_2Pushed(app, event)
            a=imread(app.uncompress.ImageSource);
            if size(a,3)==3
                R= a(:,:,1);
                G= a(:,:,2);
                B= a(:,:,3);
                RI=bitSlice(R);
                GI=bitSlice(G);
                BI=bitSlice(B);
                Img=cat(3,RI,GI,BI);
                app.compress.ImageSource=Img;
                %Img1=doubl
            elseif size(a,3)==1
                disp("hhhhhh");
                aa=HistogramEqualization(a);
                Img=bitSlice(aa);
                p='abcde.png';
                imwrite(Img,p);
                app.compress.ImageSource=p;
            else
                disp("helllo");
            end
            
            [compSize,OrigSize,CompRatio]=CalculationVal(app.uncompress.ImageSource);
            app.EditField.Value=string(OrigSize)+' Bits';
            app.EditField_2.Value=string(CompRatio)+'';
            app.EditField_3.Value=string(compSize)+' Bits';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imwrite(app.compress.ImageSource,'abc'+string(uint8(rand(1)*1000))+'.jpg');
            msgbox('Saved Successfully!','modal');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.compress.ImageSource='';
            app.uncompress.ImageSource='';
            app.EditField.Value=string(0)+' Bits';
            app.EditField_2.Value=string(0)+'';
            app.EditField_3.Value=string(0)+' Bits';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LossyCompressionPanel = uipanel(app.UIFigure);
            app.LossyCompressionPanel.Title = 'Lossy Compression';
            app.images = uipanel(app.LossyCompressionPanel);
            app.images.Title = 'Image';
            app.uncompress = uiimage(app.images);
            app.compress = uiimage(app.images);
            app.control = uipanel(app.LossyCompressionPanel);
            app.control.Title = 'Control';
            app.BrowseButton = uibutton(app.control, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CompressButton_2 = uibutton(app.control, 'push');
            app.CompressButton_2.ButtonPushedFcn = createCallbackFcn(app, @CompressButton_2Pushed, true);
            app.CompressButton_2.Text = 'Compress';
            app.SaveButton = uibutton(app.control, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.control, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.data = uipanel(app.LossyCompressionPanel);
            app.data.Title = 'Compression Info';
            app.OrignalSizeLabel = uilabel(app.data);
            app.OrignalSizeLabel.Text = 'Orignal Size';
            app.CompressionRatioLabel = uilabel(app.data);
            app.CompressionRatioLabel.Text = 'Compression Ratio';
            app.CompressionSizeLabel = uilabel(app.data);
            app.CompressionSizeLabel.Text = 'Compression Size';
            app.EditField = uieditfield(app.data, 'text');
            app.EditField.Value = '0';
            app.EditField_2 = uieditfield(app.data, 'text');
            app.EditField_2.Value = '0';
            app.EditField_3 = uieditfield(app.data, 'text');
            app.EditField_3.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageCompression

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end